classdef OnePanelFig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OnePanelFigUIFigure         matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        XAxisSciNotationCheckBox    matlab.ui.control.CheckBox
        YAxisSciNotationCheckBox    matlab.ui.control.CheckBox
        LegendEditField             matlab.ui.control.EditField
        BoldAxesCheckBox            matlab.ui.control.CheckBox
        XAxisEditField              matlab.ui.control.EditField
        YAxisEditField              matlab.ui.control.EditField
        XScaleDropDown              matlab.ui.control.DropDown
        YScaleDropDown              matlab.ui.control.DropDown
        XScaleDropDownLabel         matlab.ui.control.Label
        DelimiterLabel              matlab.ui.control.Label
        ScaleHeader                 matlab.ui.control.Label
        AxesHeader                  matlab.ui.control.Label
        LegendHeader                matlab.ui.control.Label
        LegendEditFieldLabel        matlab.ui.control.Label
        YAxisEditFieldLabel         matlab.ui.control.Label
        XAxisEditFieldLabel         matlab.ui.control.Label
        YScaleDropDownLabel         matlab.ui.control.Label
        RulerHeader                 matlab.ui.control.Label
        CenterPanel                 matlab.ui.container.Panel
        EdgeColourButton            matlab.ui.control.Button
        FaceColourButton            matlab.ui.control.Button
        Button                      matlab.ui.control.Button
        AxesObjectsDropDownLabel    matlab.ui.control.Label
        ColoursDropDown             matlab.ui.control.DropDown
        MarkerSizeEditField         matlab.ui.control.NumericEditField
        MarkerTypeDropDown          matlab.ui.control.DropDown
        LineWidthEditField          matlab.ui.control.NumericEditField
        LineStyleDropDown           matlab.ui.control.DropDown
        UpdateButton                matlab.ui.control.Button
        AxesObjectsDropDown         matlab.ui.control.DropDown
        FiguresDropDown             matlab.ui.control.DropDown
        FiguresDropDownLabel        matlab.ui.control.Label
        LineWidthEditFieldLabel     matlab.ui.control.Label
        MarkerTypeDropDownLabel     matlab.ui.control.Label
        FiguresHeader               matlab.ui.control.Label
        LineStyleDropDownLabel      matlab.ui.control.Label
        StyleHeader                 matlab.ui.control.Label
        MarkerSizeEditFieldLabel    matlab.ui.control.Label
        ColoursHeader               matlab.ui.control.Label
        ColoursDropDownLabel        matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        XTickSpacingEditFieldLabel  matlab.ui.control.Label
        ScriptButton                matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        LoadButton                  matlab.ui.control.Button
        FigPresetDropDown           matlab.ui.control.DropDown
        XTickSpacingEditField       matlab.ui.control.NumericEditField
        SpanButton                  matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
        UpperYEditField             matlab.ui.control.NumericEditField
        LowerYEditField             matlab.ui.control.NumericEditField
        UpperXEditField             matlab.ui.control.NumericEditField
        LowerXEditField             matlab.ui.control.NumericEditField
        InvertX                     matlab.ui.control.ToggleSwitch
        InvertY                     matlab.ui.control.ToggleSwitch
        UpperXEditFieldLabel        matlab.ui.control.Label
        UpperYEditFieldLabel        matlab.ui.control.Label
        LowerYEditFieldLabel        matlab.ui.control.Label
        LowerXEditFieldLabel        matlab.ui.control.Label
        FigPresetDropDownLabel      matlab.ui.control.Label
        InvertHeader                matlab.ui.control.Label
        InvertXLabel                matlab.ui.control.Label
        InvertYLabel                matlab.ui.control.Label
        LimitsHeader                matlab.ui.control.Label
        PresetsHeader               matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        fig % gcf global variable
        ax % gca global variable
        currentHandle % Property to hold current handle selected
        currentObj % Property to hold current object selected in axes
        legends % Global variable for legend label
        ColorPallet % Global variable to be changed based on colour selection
        initialX % Property to hold the initial X limits
        initialY % Property to hold the initial Y limits
        LineStyle % Property to hold the characters of associated linestyle
        Marker % Global variable to hold which marker is being used
        oldMarker % Global variable to hold non-connecting marker
        Size % Global variable to hold marker size
        Width % Global variable to hold line width
        figHandles % Global variable to hold all figure handles
        Num % Global variable to hold either num of data sets or num of lines
        objects % Holds handle to all line or scatter objects on fig. Cannot mix the two
        indiColors % Property to hold individual colors
        ObjIndex % Property to hold index of current obj
        %         OBJ % Property to hold all objects of current fig

        % Property to hold all header labels for preset
        headersToSave = {'ScaleHeader', 'AxesHeader', 'LegendHeader', 'DelimiterLabel', 'RulerHeader', 'FiguresHeader', 'StyleHeader',...
            'ColoursHeader', 'InvertHeader', 'LimitsHeader', 'PresetsHeader'}

        % Property to hold all associated labels for preset
        labelsToSave = {'YScaleDropDownLabel', 'XScaleDropDownLabel', 'YAxisEditFieldLabel', 'XAxisEditFieldLabel', 'LegendEditFieldLabel', ...
            'LineStyleDropDownLabel', 'LineWidthEditFieldLabel', 'MarkerTypeDropDownLabel', 'MarkerSizeEditFieldLabel','ColoursDropDownLabel', ...
            'InvertYLabel', 'InvertXLabel', 'LowerXEditFieldLabel', 'UpperXEditFieldLabel','LowerYEditFieldLabel', 'UpperYEditFieldLabel','XTickSpacingEditFieldLabel','FigPresetDropDownLabel'}

        % Property to hold all values for preset
        valuesToSave = {'YScaleDropDown', 'XScaleDropDown', 'YAxisEditField', 'XAxisEditField', 'BoldAxesCheckBox', 'LegendEditField', 'YAxisSciNotationCheckBox', 'XAxisSciNotationCheckBox', ...
            'LineStyleDropDown', 'LineWidthEditField', 'MarkerTypeDropDown', 'MarkerSizeEditField', 'ColoursDropDown', ...
            'InvertY', 'InvertX', 'LowerXEditField', 'UpperXEditField','LowerYEditField', 'UpperYEditField', 'XTickSpacingEditField', 'FigPresetDropDown'}
    end

    methods (Access = private)
        % Functions to choose/set colours
        function colors = distinguishable_colors(app,n_colors,bg,func)
            % DISTINGUISHABLE_COLORS: pick colors that are maximally perceptually distinct
            %
            % When plotting a set of lines, you may want to distinguish them by color.
            % By default, Matlab chooses a small set of colors and cycles among them,
            % and so if you have more than a few lines there will be confusion about
            % which line is which. To fix this problem, one would want to be able to
            % pick a much larger set of distinct colors, where the number of colors
            % equals or exceeds the number of lines you want to plot. Because our
            % ability to distinguish among colors has limits, one should choose these
            % colors to be "maximally perceptually distinguishable."
            %
            % This function generates a set of colors which are distinguishable
            % by reference to the "Lab" color space, which more closely matches
            % human color perception than RGB. Given an initial large list of possible
            % colors, it iteratively chooses the entry in the list that is farthest (in
            % Lab space) from all previously-chosen entries. While this "greedy"
            % algorithm does not yield a global maximum, it is simple and efficient.
            % Moreover, the sequence of colors is consistent no matter how many you
            % request, which facilitates the users' ability to learn the color order
            % and avoids major changes in the appearance of plots when adding or
            % removing lines.
            %
            % Syntax:
            %   colors = distinguishable_colors(n_colors)
            % Specify the number of colors you want as a scalar, n_colors. This will
            % generate an n_colors-by-3 matrix, each row representing an RGB
            % color triple. If you don't precisely know how many you will need in
            % advance, there is no harm (other than execution time) in specifying
            % slightly more than you think you will need.
            %
            %   colors = distinguishable_colors(n_colors,bg)
            % This syntax allows you to specify the background color, to make sure that
            % your colors are also distinguishable from the background. Default value
            % is white. bg may be specified as an RGB triple or as one of the standard
            % "ColorSpec" strings. You can even specify multiple colors:
            %     bg = {'w','k'}
            % or
            %     bg = [1 1 1; 0 0 0]
            % will only produce colors that are distinguishable from both white and
            % black.
            %
            %   colors = distinguishable_colors(n_colors,bg,rgb2labfunc)
            % By default, distinguishable_colors uses the image processing toolbox's
            % color conversion functions makecform and applycform. Alternatively, you
            % can supply your own color conversion function.
            %
            % Example:
            %   c = distinguishable_colors(25);
            %   figure
            %   image(reshape(c,[1 size(c)]))
            %
            % Example using the file exchange's 'colorspace':
            %   func = @(x) colorspace('RGB->Lab',x);
            %   c = distinguishable_colors(25,'w',func);

            % Copyright 2010-2011 by Timothy E. Holy

            % Parse the inputs
            if (nargin < 3)
                bg = [1 1 1];  % default white background
            else
                if iscell(bg)
                    % User specified a list of colors as a cell aray
                    bgc = bg;
                    for i = 1:length(bgc)
                        bgc{i} = parsecolor(app,bgc{i});
                    end
                    bg = cat(1,bgc{:});
                else
                    % User specified a numeric array of colors (n-by-3)
                    bg = parsecolor(app, bg);
                end
            end

            % Generate a sizable number of RGB triples. This represents our space of
            % possible choices. By starting in RGB space, we ensure that all of the
            % colors can be generated by the monitor.
            n_grid = 30;  % number of grid divisions along each axis in RGB space
            x = linspace(0,1,n_grid);
            [R,G,B] = ndgrid(x,x,x);
            rgb = [R(:) G(:) B(:)];
            if (n_colors > size(rgb,1)/3)
                error('You can''t readily distinguish that many colors');
            end

            % Convert to Lab color space, which more closely represents human
            % perception
            if (nargin > 3)
                lab = func(rgb);
                bglab = func(bg);
            else
                C = makecform('srgb2lab');
                lab = applycform(rgb,C);
                bglab = applycform(bg,C);
            end

            % If the user specified multiple background colors, compute distances
            % from the candidate colors to the background colors
            mindist2 = inf(size(rgb,1),1);
            for i = 1:size(bglab,1)-1
                dX = bsxfun(@minus,lab,bglab(i,:)); % displacement all colors from bg
                dist2 = sum(dX.^2,2);  % square distance
                mindist2 = min(dist2,mindist2);  % dist2 to closest previously-chosen color
            end

            % Iteratively pick the color that maximizes the distance to the nearest
            % already-picked color
            colors = zeros(n_colors,3);
            lastlab = bglab(end,:);   % initialize by making the "previous" color equal to background
            for i = 1:n_colors
                dX = bsxfun(@minus,lab,lastlab); % displacement of last from all colors on list
                dist2 = sum(dX.^2,2);  % square distance
                mindist2 = min(dist2,mindist2);  % dist2 to closest previously-chosen color
                [~,index] = max(mindist2);  % find the entry farthest from all previously-chosen colors
                colors(i,:) = rgb(index,:);  % save for output
                lastlab = lab(index,:);  % prepare for next iteration
            end
        end

        function c = parsecolor(app,s)
            if ischar(s)
                c = colorstr2rgb(app,s);
            elseif isnumeric(s) && size(s,2) == 3
                c = s;
            else
                error('MATLAB:InvalidColorSpec','Color specification cannot be parsed.');
            end
        end

        function c = colorstr2rgb(~,c)
            % Convert a color string to an RGB value.
            % This is cribbed from Matlab's whitebg function.
            % Why don't they make this a stand-alone function?
            rgbspec = [1 0 0;0 1 0;0 0 1;1 1 1;0 1 1;1 0 1;1 1 0;0 0 0];
            cspec = 'rgbwcmyk';
            k = find(cspec==c(1));
            if isempty(k)
                error('MATLAB:InvalidColorString','Unknown color string.');
            end
            if k~=3 || length(c)==1
                c = rgbspec(k,:);
            elseif length(c)>2
                if strcmpi(c(1:3),'bla')
                    c = [0 0 0];
                elseif strcmpi(c(1:3),'blu')
                    c = [0 0 1];
                else
                    error('MATLAB:UnknownColorString', 'Unknown color string.');
                end
            end
        end

        % Function to set data properties
        function setDataProp(app)
            % IF ANYTHING BREAKS DOWN IT IS MOST LIKELY HERE.
            % NOT GENERAL AND NEEDS PROPERTY ASSIGNMENT DEPENDING ON TYPE
            % Could probably make it more efficient by switch case

            num = app.Num;
            Objects = app.objects;

%             for i = 1:num
%                 if strcmp(Objects(i,1).Type,"constantline")
%                     Objects(i) = []; % Erase any ylines from list
%                 end
%             end

            % Check if our object is a graphics object and preallocate type
%             tf = isgraphics(Objects);
            typeString = strings(length(Objects),1);

            for i = num:-1:1 % Im going to try this backwards. num:-1:1 1:num
                typeString(i) = string(Objects(i).Type);
                %                 typeString = convertCharsToStrings(type(1,:));
                %                 typeString(i) = convertCharsToStrings(type(i));

                %             if length(tf) > 1
                %
                %             else
                %                 type = Objects.Type;
                %                 typeString = convertCharsToStrings(type(1,:));
                %             end

                %             % Find type
                %             try
                %
                %             catch
                %                 % If line is mixed with scatter (e.g. in a fit plot)
                %                 for i = 1:size(Objects,1)
                %                     %                     typeArray(i,1) = convertCharsToStrings(Objects(i,1).Type);
                %                     if strcmp(Objects(i,1).Type,"line")
                %                         Objects(i) = []; % Erase any fit lines from list and only edit scatter
                %                     end
                %                 end
                %                 typeString = 'scatter';
                %             end


                % Add check in case app is started first and updated with 0x0 str array (app.legends)
                if isempty(app.legends)
                    app.legends = (strings(num,1));
                end

                % Determine the type of plot
                if strcmp(typeString(i), 'line') == 1
                    
                    % Alter GUI accordingly to each type
                    app.LineWidthEditField.Editable = 1;
                    app.LineWidthEditField.Enable = 1;
                    app.LineWidthEditFieldLabel.Enable = 1;
                    app.LineStyleDropDown.Editable = 1;
                    app.LineStyleDropDown.Enable = 1;
                    app.LineStyleDropDownLabel.Enable = 1;

                    if isempty(app.ColorPallet)
                        app.ColorPallet = distinguishable_colors(app,num);
                    end

                    if app.Marker == "None"
                        Objects(i).Marker = "none";
                    else
                        Objects(i).Marker = app.Marker;
                    end
                    Objects(i).MarkerSize = app.Size;
                    Objects(i).LineWidth = app.Width;

                    if isempty(Objects(i).UserData)
                        Objects(i).Color = app.ColorPallet(i,:);
                    else
                        ...
                    end
                    if ~isempty(app.legends)
                        Objects(i).DisplayName = app.legends(i,:);
                    end

                elseif strcmp(typeString(i), 'scatter') == 1 % Determine the type of plot (SCATTER?)

                    % Alter GUI accordingly to each type
                    app.MarkerTypeDropDown.Editable = 1;
                    app.MarkerTypeDropDown.Enable = 1;
                    app.MarkerTypeDropDownLabel.Enable = 1;
                    app.MarkerSizeEditField.Editable = 1;
                    app.MarkerSizeEditField.Enable = 1;
                    app.MarkerSizeEditFieldLabel.Enable = 1;

                    app.LineWidthEditField.Editable = 0;
                    app.LineWidthEditField.Enable = 0;
                    app.LineWidthEditFieldLabel.Enable = 0;
                    app.LineStyleDropDown.Editable = 0;
                    app.LineStyleDropDown.Enable = 0;
                    app.LineStyleDropDownLabel.Enable = 0;

                    % Then apply properties
                    if isempty(app.ColorPallet)
                        app.ColorPallet = distinguishable_colors(app,num);
                    end

                    if app.Marker == "None"
                        Objects(i).Marker = "square";
                        app.MarkerTypeDropDown.Value = "Square";
                    else
                        Objects(i).Marker = app.Marker;
                    end
                    Objects(i).SizeData = app.Size;


                    if isempty(Objects(i).UserData)
                        Objects(i).MarkerEdgeColor = app.ColorPallet(i,:);
                    else
                        ...
                    end

                    Objects(i).MarkerFaceColor = Objects(i).MarkerEdgeColor;
                    if ~isempty(app.legends)
                        Objects(i).DisplayName = app.legends(i,:);
                    end

                elseif strcmp(typeString(i), 'errorbar')

                    % Alter GUI accordingly to each type
                    app.MarkerTypeDropDown.Editable = 1;
                    app.MarkerTypeDropDown.Enable = 1;
                    app.MarkerTypeDropDownLabel.Enable = 1;
                    app.MarkerSizeEditField.Editable = 1;
                    app.MarkerSizeEditField.Enable = 1;
                    app.MarkerSizeEditFieldLabel.Enable = 1;

                    app.LineWidthEditField.Editable = 1;
                    app.LineWidthEditField.Enable = 1;
                    app.LineWidthEditFieldLabel.Enable = 1;
                    app.LineStyleDropDown.Editable = 1;
                    app.LineStyleDropDown.Enable = 1;
                    app.LineStyleDropDownLabel.Enable = 1;

                    % Then apply properties
                    if isempty(app.ColorPallet)
                        app.ColorPallet = distinguishable_colors(app,num);
                    end

                    if app.Marker == "None"
                        Objects(i).Marker = "none";
                    else
                        Objects(i).Marker = app.Marker;
                    end
                    Objects(i).MarkerSize = app.Size;
                    Objects(i).LineWidth = app.Width;

                    if isempty(Objects(i).UserData)
                        Objects(i).MarkerEdgeColor = app.ColorPallet(i,:);
                    else
                        ...
                    end

                    Objects(i).MarkerFaceColor = Objects(i).MarkerEdgeColor;
                    if ~isempty(app.legends)
                        Objects(i).DisplayName = app.legends(i,:);
                    end

                elseif strcmp(typeString(i), 'bar')
                    % Alter GUI accordingly to each type
                    app.LineWidthEditField.Editable = 1;
                    app.LineWidthEditField.Enable = 1;
                    app.LineWidthEditFieldLabel.Enable = 1;
                    app.LineStyleDropDown.Editable = 1;
                    app.LineStyleDropDown.Enable = 1;
                    app.LineStyleDropDownLabel.Enable = 1;

                    app.MarkerTypeDropDown.Editable = 0;
                    app.MarkerTypeDropDown.Enable = 0;
                    app.MarkerTypeDropDownLabel.Enable = 0;
                    app.MarkerSizeEditField.Editable = 0;
                    app.MarkerSizeEditField.Enable = 0;
                    app.MarkerSizeEditFieldLabel.Enable = 0;

                    if isempty(app.ColorPallet)
                        app.ColorPallet = distinguishable_colors(app,num);
                    end

                    Objects(i).LineWidth = app.Width;
                    if isempty(Objects(i).UserData)
                        Objects(i).EdgeColor = app.ColorPallet(i,:);
                    else
                        ...
                    end

                    Objects(i).FaceColor = Objects(i).EdgeColor;
                    if ~isempty(app.legends)
                        Objects(i).DisplayName = app.legends(i,:);
                    end

                elseif strcmp(typeString(i),'stair')

                    % Alter GUI accordingly to each type
                    app.MarkerTypeDropDown.Editable = 1;
                    app.MarkerTypeDropDown.Enable = 1;
                    app.MarkerTypeDropDownLabel.Enable = 1;
                    app.MarkerSizeEditField.Editable = 1;
                    app.MarkerSizeEditField.Enable = 1;
                    app.MarkerSizeEditFieldLabel.Enable = 1;

                    app.LineWidthEditField.Editable = 1;
                    app.LineWidthEditField.Enable = 1;
                    app.LineWidthEditFieldLabel.Enable = 1;
                    app.LineStyleDropDown.Editable = 1;
                    app.LineStyleDropDown.Enable = 1;
                    app.LineStyleDropDownLabel.Enable = 1;

                    % Then apply properties
                    if isempty(app.ColorPallet)
                        app.ColorPallet = distinguishable_colors(app,num);
                    end

                    if app.Marker == "None"
                        Objects(i).Marker = "none";
                    else
                        Objects(i).Marker = app.Marker;
                    end
                    Objects(i).MarkerSize = app.Size;
                    Objects(i).LineWidth = app.Width;

                    if isempty(Objects(i).UserData)
                        Objects(i).MarkerEdgeColor = app.ColorPallet(i,:);
                    else
                        ...
                    end

                    Objects(i).MarkerFaceColor = Objects(i).MarkerEdgeColor;
                    if ~isempty(app.legends)
                        Objects(i).DisplayName = app.legends(i,:);
                    end
                else
                    return
                end
            end

            l = legend(Objects,'Location','best','fontsize',12,'Box','off'); % Flip objects here?
            if isempty(app.legends) || app.legends(i,:) == ""
                l.Visible = false;
            else
                l.Visible = true;
            end
            app.LegendEditField.Value = strjoin(app.legends,',');

            %             app.objects = Objects;
        end

        % Callback function to handle close request/preset save
        function dlgcallback(app,~,event)
            if event.SelectedOption == "Yes"
                SaveButtonPushed(app);
                delete(app);
            else
                delete(app);
            end
        end

        % Function to reapply the GUI values to selected figure
        function repopulate(app)
            YScaleDropDownValueChanged(app);
            XScaleDropDownValueChanged(app);
            YAxisEditFieldValueChanged(app);
            XAxisEditFieldValueChanged(app);
            BoldAxesCheckBoxValueChanged(app);
            LegendEditFieldValueChanged(app);
            YAxisSciNotationCheckBoxValueChanged(app);
            XAxisSciNotationCheckBoxValueChanged(app);

            if app.currentObj.UserData == []
                ColoursDropDownValueChanged(app);
            end
            
            if app.LineStyleDropDown.Enable == 1
                LineStyleDropDownValueChanged(app);
            end
            LineWidthEditFieldValueChanged(app);
            MarkerTypeDropDownValueChanged(app);
            MarkerSizeEditFieldValueChanged(app);
            InvertYValueChanged(app);
            InvertXValueChanged(app);
            LowerXEditFieldValueChanged(app);
            UpperXEditFieldValueChanged(app);
            LowerYEditFieldValueChanged(app);
            UpperYEditFieldValueChanged(app);
            FigPresetDropDownValueChanged(app);
        end

        % Function to find all open figures and assign handle numbers
        function findFigures(app)
            g = groot;

            % Find all figures
            app.figHandles = gobjects(size(g));
            app.figHandles = g.Children;
            sz = size(app.figHandles,1);
            if sz == 0
                return % End function if no figures are found
            end
            items = strings(sz,1);

            for i=1:sz
                items(i) = strcat("Figure ", num2str(app.figHandles(i).Number)); % Set figure name and number
                app.figHandles(i).CloseRequestFcn = @(src,event)deletedFigures(app,src,event); % Set close request callback
            end

            % Populate dropdown
            app.FiguresDropDown.Items = items;
            app.currentHandle = app.figHandles(1);

            %             listener(g,'CurrentFigure','PostSet',@(src,event)updateFigures(app,src,event));
        end

        % Function to hold the general setup done on startup for new figs
        function generalSetup(app)
            % Set initial fig properties
            app.fig = gcf;
            app.ax = gca;

            app.fig.Color = 'white';
            app.fig.WindowStyle = 'normal';
            app.fig.Units = 'centimeters';

            % Set initial axes properties
            app.ax.Color = 'white';
            app.ax.LineWidth = 1.5;
            app.ax.XGrid = 'off';
            app.ax.YGrid = 'off';
            app.ax.Box = 'on';
            app.ax.FontName = 'Arial';
            app.ax.Units = 'centimeters';
            app.ax.XMinorTick = 'on';
            app.ax.YMinorTick = 'on';
            app.ax.TickLength = [0.03 0.01];
            app.ax.PositionConstraint = 'outerposition';

            app.initialX = app.ax.XLim;
            app.initialY = app.ax.YLim;

            if app.LowerXEditField.Value == 0 && app.UpperXEditField.Value == 1
                app.LowerXEditField.Value = app.initialX(1);
                app.UpperXEditField.Value = app.initialX(2);
            end
            if app.LowerYEditField.Value == 0 && app.UpperYEditField.Value == 1
                app.LowerYEditField.Value = app.initialY(1);
                app.UpperYEditField.Value = app.initialY(2);
            end
        end

        % Function to handle when a figure is deleted to update dropdown
        function deletedFigures(app,src,~)
            delete(src);
            if isvalid(app) == 1
                app.ScriptButton.UserData = 0;
                UpdateButtonPushed(app);
            end
        end

        % Function to disable GUI elements if no figures are present
        function enable_disable(app,bool)
            if bool == 0
                app.XAxisSciNotationCheckBox.Enable = "off";
                app.YAxisSciNotationCheckBox.Enable = "off";
                app.LegendEditField.Enable = "off";
                app.BoldAxesCheckBox.Enable = "off";
                app.XAxisEditField.Enable = "off";
                app.YAxisEditField.Enable = "off";
                app.XScaleDropDown.Enable = "off";
                app.YScaleDropDown.Enable = "off";
                app.ColoursDropDown.Enable = "off";
                app.MarkerSizeEditField.Enable = "off";
                app.MarkerTypeDropDown.Enable = "off";
                app.LineWidthEditField.Enable = "off";
                app.LineStyleDropDown.Enable = "off";
                app.ScriptButton.Enable = "off";
                app.SaveButton.Enable = "off";
                app.LoadButton.Enable = "off";
                app.FigPresetDropDown.Enable = "off";
                app.SpanButton.Enable = "off";
                app.ResetButton.Enable = "off";
                app.UpperYEditField.Enable = "off";
                app.LowerYEditField.Enable = "off";
                app.UpperXEditField.Enable = "off";
                app.LowerXEditField.Enable = "off";
                app.InvertX.Enable = "off";
                app.InvertY.Enable = "off";
                app.FiguresDropDown.Items = {};
                app.AxesObjectsDropDown.Items = {};
            else
                app.XAxisSciNotationCheckBox.Enable = "on";
                app.YAxisSciNotationCheckBox.Enable = "on";
                app.LegendEditField.Enable = "on";
                app.BoldAxesCheckBox.Enable = "on";
                app.XAxisEditField.Enable = "on";
                app.YAxisEditField.Enable = "on";
                app.XScaleDropDown.Enable = "on";
                app.YScaleDropDown.Enable = "on";
                app.ColoursDropDown.Enable = "on";
                app.MarkerSizeEditField.Enable = "on";
                app.MarkerTypeDropDown.Enable = "on";
                app.LineWidthEditField.Enable = "on";
                app.LineStyleDropDown.Enable = "on";
                app.ScriptButton.Enable = "on";
                app.SaveButton.Enable = "on";
                app.LoadButton.Enable = "on";
                app.FigPresetDropDown.Enable = "on";
                app.SpanButton.Enable = "on";
                app.ResetButton.Enable = "on";
                app.UpperYEditField.Enable = "on";
                app.LowerYEditField.Enable = "on";
                app.UpperXEditField.Enable = "on";
                app.LowerXEditField.Enable = "on";
                app.InvertX.Enable = "on";
                app.InvertY.Enable = "on";
            end
        end

        % Functino to find all graphics objects in current Figure
        function [Objects,names] = updateGobjects(app)
            %% Set data properties
            app.objects = []; % Erase previous data

            if isempty(app.ax)
                AX = gca;
            else
                AX = app.ax;
            end

            % Find all objects in axes and size, and set to properties
            Objects = flipud(allchild(AX));

            num = size(Objects,1);


            names = strings;

            %             % Incase there is a legend present, changes dot notation/index
            %             check = isgraphics(app.currentHandle.Children);
            %             if size(check,1) > 1 % If the Children is not just the axes
            %                 flippedAxes = flip(app.currentHandle.Children);
            %                 axesChild = flippedAxes(1); % IS THIS ALWAYS 1? AXES COMES FIRST THEN LEGEND AND OTHERS?
            %             else
            %                 axesChild = app.currentHandle.Children;
            %             end

            %             Objs = axesChild.Children;
            %             Objs = app.objects;
            %             num = app.Num;
            %             sz = size(Objs,1);
            for i = 1:num
%                 Objects(i,1).UserData = i;
                Objects(i,1).ButtonDownFcn = @(src,event)clickedObj(app,src,event);
                %                 types{i,1} = Objs(i,1).Type;

                if ~isempty(Objects(i,1).DisplayName)
                    names(i,1) = Objects(i,1).DisplayName; % Names in order of flipped objects
                else
                    tp = string(Objects(i,1).Type);
                    names(i,1) = string(tp + num2str(i)); % Names correspond to flipped objects from type 1, 2, ...
%                     names = flip(names);
                end
            end

            names = flip(names);

            app.objects = (Objects); % remove flip: objects(1) = 1
            app.legends = (names);
            app.Num = num;

            %             app.OBJ = Objects;
        end

        function setFontColor(app)
            try
                type = app.currentObj.Type;
            catch e
                disp(e)
                uialert(app.OnePanelFigUIFigure,"Error with current object please update and try again","setFontColor Error")
            end

            % Set color for recognizability
            if strcmp(type,'scatter')
                c = app.currentObj.MarkerEdgeColor;
                if ischar(c)
                    c = app.currentObj.CData;
                end
            elseif strcmp(type,'bar')
                c = app.currentObj.FaceColor;
            else
                c = app.currentObj.Color;
            end
            app.AxesObjectsDropDown.FontColor = c;
        end

        function clickedObj(app,src,~)

            Index = src.UserData;
            app.ObjIndex = Index;
            obj = flipud(app.objects);

            app.currentObj = obj(Index,1);
            items = flip(app.AxesObjectsDropDown.Items);
            app.AxesObjectsDropDown.Value = items(Index);
            setFontColor(app);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Check if MATLAB addons are installed
            try
                matlab.addons.isAddonEnabled('Image Processing Toolbox');
            catch error
                disp(error);
                uialert(app.OnePanelFigUIFigure,"Please ensure the 'Image Processing Toolbox' is installed to continue","Addon Required","Icon","error");
                pause(5);
                delete(app);
            end

            % Find and numerically label handles of all open figures
            auto = 0;
            g = groot;
            if isempty(g.Children)
                uialert(app.OnePanelFigUIFigure,"There is no figure open. Please open one and update the selection","Empty Handle","Icon","warning");
                enable_disable(app,0); % Disable GUI elements that can't be used until updated
                auto = 1;
            else
                findFigures(app); % Find all open figures and populate dropdown

                % Find all axes objects for current figure
                [Objs,names] = updateGobjects(app);
                names = flip(names);
                app.AxesObjectsDropDown.Items = names;
                app.AxesObjectsDropDown.Value = names(1);
                app.currentObj = Objs(1);
                app.ObjIndex = 1;

                app.indiColors = false(size(Objs,1),1);
            end

            % General setup and styling
            generalSetup(app);

            % Initialize empty properties to default values
            num1 = size(findobj(app.fig,'Type','Line'),1);
            num2 = size(findobj(app.fig,'Type','Scatter'),1);
            num3 = size(findobj(app.fig,'Type','errorbar'),1);

            nums = num1+num2+num3;
            if exist("names","var")
                app.legends = (names);
            else
                app.legends = (strings(nums,1));
            end

            app.Size = 10;
            app.Width = 2;

            if ~isempty(findobj(app.fig,'Type','Scatter'))
                app.MarkerTypeDropDown.Value = 'Square';
                app.Marker = 'square';
                app.FaceColourButton.Enable = "on";
                app.EdgeColourButton.Enable = "on";
            else
                app.Marker = 'none';
            end
            app.LineStyle = '--';
            app.ScriptButton.UserData = 0; % To count each time pressed

            % Set data properties based on plot type (line or scatter)
            if auto == 0
                setDataProp(app);
            end

            app.ax.FontSize = 14; % This unsuspecting command changes limit functionality. KEEP HERE

            % Get rid of extra white space around figure
            CurrentFigPos = app.fig.Position;

            % Default aspect ratio
            app.ax.OuterPosition = [0 0 14.8167 8];
            app.fig.Position = [CurrentFigPos(1) CurrentFigPos(2) 14.8167 8];

            % Changing limits back since functions above skew them
            app.ax.XLim = app.initialX;
            app.ax.YLim = app.initialY;

            % GUI should reflect default values
            app.LowerXEditField.Value = app.ax.XLim(1);
            app.UpperXEditField.Value = app.ax.XLim(2);
            app.LowerYEditField.Value = app.ax.YLim(1);
            app.UpperYEditField.Value = app.ax.YLim(2);
            app.MarkerSizeEditField.Value = app.Size;
            app.LineWidthEditField.Value = app.Width;

            if auto == 1
                close(gcf);
                return;
            end

            setFontColor(app);
        end

        % Value changed function: YScaleDropDown
        function YScaleDropDownValueChanged(app, event)
            value = app.YScaleDropDown.Value;
            app.ax.YScale = value;
            app.ax.YMinorTick = 'on';
        end

        % Value changed function: XScaleDropDown
        function XScaleDropDownValueChanged(app, event)
            value = app.XScaleDropDown.Value;
            app.ax.XScale = value;
            app.ax.XMinorTick = 'on';
        end

        % Value changed function: YAxisEditField
        function YAxisEditFieldValueChanged(app, event)
            value = app.YAxisEditField.Value;
            ylabel(app.ax,value);
        end

        % Value changed function: XAxisEditField
        function XAxisEditFieldValueChanged(app, event)
            value = app.XAxisEditField.Value;
            xlabel(app.ax,value);
        end

        % Value changed function: BoldAxesCheckBox
        function BoldAxesCheckBoxValueChanged(app, event)
            value = app.BoldAxesCheckBox.Value;
            if value == 1
                app.ax.FontWeight = 'bold';
            else
                app.ax.FontWeight = 'normal';
            end
        end

        % Value changed function: LegendEditField
        function LegendEditFieldValueChanged(app, event)
            value = app.LegendEditField.Value;
            names = split(value, {',',';'});
            names = strtrim(names);


            app.legends = strings(app.Num,1);
            if names == ""
                [~,names] = updateGobjects(app);
                app.legends = (names);
            else
                try
                    %                     [app.legends{:}] = names{:,1};
%                     app.legends = string(names); Flip the legend to be in
%                     order?
                    app.legends = (string(names));
                catch
                    uialert(app.OnePanelFigUIFigure,"Please enter a sufficient number of legend entries.", "Insufficient Input", "Icon","error");
                end
            end

            for i = 1:size(app.legends,1)
                if i > size(app.objects)
                    break
                end
                app.objects(i).DisplayName = app.legends(i,1);
            end
        end

        % Value changed function: YAxisSciNotationCheckBox
        function YAxisSciNotationCheckBoxValueChanged(app, event)
            value = app.YAxisSciNotationCheckBox.Value;

            if value == 1
                mag = floor(log(abs(app.ax.YLim(2)))./log(10));
                app.ax.YRuler.Exponent = mag;
            else
                app.ax.YRuler.Exponent = 0;
            end
        end

        % Value changed function: XAxisSciNotationCheckBox
        function XAxisSciNotationCheckBoxValueChanged(app, event)
            value = app.XAxisSciNotationCheckBox.Value;

            if value == 1
                mag = floor(log(abs(app.ax.XLim(2)))./log(10));
                app.ax.XRuler.Exponent = mag;
            else
                app.ax.XRuler.Exponent = 0;
            end
        end

        % Value changed function: FiguresDropDown
        function FiguresDropDownValueChanged(app, event)
            value = app.FiguresDropDown.Value;
            g = groot;
            % Extract handle number for identifier
            num = regexp(value, '\d*', 'Match');
            int = str2double(num);

            % Find the handle associated with the number
            try
                for i = 1:size(app.figHandles,1)
                    if int == app.figHandles(i).Number
                        app.currentHandle = app.figHandles(i);
                        break
                    end
                end
            catch
                uialert(app.OnePanelFigUIFigure,"Figure handle no longer exists, update and try again","Invalid/Deleted Figure");
                return
            end

            % Reset this to new gcf
            g.CurrentFigure = app.currentHandle;

            % Repopulate current figure to new handle and setup basics
            generalSetup(app);
            app.ax.FontSize = 14; % This unsuspecting command changes limit functionality. KEEP HERE

            %             % Reapply number of legend entries
            %             nums = allchild(app.ax);
            %             app.legends = strings(size(nums));

            % Find all axes objects for current figure
            [Objs,names] = updateGobjects(app);
            app.AxesObjectsDropDown.Items = names;
            app.AxesObjectsDropDown.Value = names(1);
            app.currentObj = Objs(1);
            app.legends = (names);

            app.indiColors = false(size(Objs,1),1);

            repopulate(app); % Set all other properties
            setFontColor(app);
        end

        % Value changed function: AxesObjectsDropDown
        function AxesObjectsDropDownValueChanged(app, event)
            value = app.AxesObjectsDropDown.Value;

            %             tst = event.Source;
            %             new = event.Value;
            %             prev = event.PreviousValue;

            [Objs,names] = updateGobjects(app);  % Find currentHandle objs
            Index = strcmp(names,value); % Find index of matching dropdown value
            app.ObjIndex = Index;

            app.currentObj = Objs(Index,1); % Set new currentObj for color edits
            app.currentObj

            setFontColor(app);
        end

        % Button pushed function: Button
        function PaletteButtonPushed(app, event)
            %Pick color manually
            color = uisetcolor();
            if color == 0
                return
            end

            if strcmp(app.currentObj.Type,'scatter')
                app.currentObj.MarkerEdgeColor = color;
                app.currentObj.MarkerFaceColor = color;
            else
                app.currentObj.Color = color;
            end

            app.AxesObjectsDropDown.FontColor = color;
            app.currentObj.UserData = 1; % UserData for this GUI
            app.indiColors(app.ObjIndex,1) = true; % indiColors for external scripts
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            g = groot;
            findFigures(app);
            if isempty(g.Children)
                uialert(app.OnePanelFigUIFigure,"There is no figure open. Please open one and update the selection. Note the script save number has also reset","Empty Handle","Icon","warning");
                enable_disable(app,0); % Disable GUI elements that can't be used until updated
                return
            else
                enable_disable(app,1); % Enable GUI elements
                FiguresDropDownValueChanged(app);
                setDataProp(app);
                setFontColor(app);
            end
        end

        % Value changed function: LineStyleDropDown
        function LineStyleDropDownValueChanged(app, event)
            value = string(app.LineStyleDropDown.Value);

            if value == "Solid"
                app.ax.LineStyleOrder = '-';
            elseif value == "Dashed"
                app.ax.LineStyleOrder = '--';
            elseif value == "Dotted"
                app.ax.LineStyleOrder = ':';
            elseif value == "Dash-Dot"
                app.ax.LineStyleOrder = '-.';
            end

            app.LineStyle = app.ax.LineStyleOrder;

%             for i = 1:app.Num
%                 app.objects(i).LineStyle = app.LineStyle;
%             end
        end

        % Value changed function: LineWidthEditField
        function LineWidthEditFieldValueChanged(app, event)
            value = app.LineWidthEditField.Value;
            if value <= 0
                uialert(app.OnePanelFigUIFigure,"Enter a value greater than zero","LineWidthError","Icon","error")
            else
                app.Width = value;
            end

            for i = 1:app.Num
                app.objects(i).LineWidth = app.Width;
            end
        end

        % Value changed function: MarkerTypeDropDown
        function MarkerTypeDropDownValueChanged(app, event)
            value = string(app.MarkerTypeDropDown.Value);

            app.FaceColourButton.Enable = "on";
            app.EdgeColourButton.Enable = "on";

            if value == "None"
                app.Marker = 'none';
                app.FaceColourButton.Enable = "off";
                app.EdgeColourButton.Enable = "off";
            elseif value == "Square"
                app.Marker = 'square';
            elseif value == "Circle"
                app.Marker = "o";
            elseif value == "Point"
                app.Marker = '.';
            elseif value == "Plus Sign"
                app.Marker = '+';
            elseif value == "Asterisk"
                app.Marker = '*';
            elseif value == "Cross"
                app.Marker = 'x';
            elseif value == "Horizontal Line"
                app.Marker = '_';
            elseif value == "Vertical Line"
                app.Marker = '|';
            elseif value == "Diamond"
                app.Marker = 'diamond';
            end

            for i = 1:app.Num
                try
                    app.objects(i).Marker = app.Marker;
                catch
                    % If an object does not have a marker then do nothing
                end
            end 
%             try
%                 for i = 1:app.Num
%                     app.objects(i).Marker = app.Marker;
%                 end
%             catch
%                 % If an object does not have a marker then do nothing
%             end
        end

        % Value changed function: MarkerSizeEditField
        function MarkerSizeEditFieldValueChanged(app, event)
            value = app.MarkerSizeEditField.Value;

            if value <= 0
                uialert(app.OnePanelFigUIFigure,"Enter a value greater than zero","MarkerSzError","Icon","error")
            else
                app.Size = value;
            end
            for i = 1:app.Num
                if strcmp(app.objects(i).Type,'scatter')
                    app.objects(i).SizeData = app.Size;
                elseif strcmp(app.objects(i).Type, 'constantline')
                    continue
                else
                    app.objects(i).MarkerSize = app.Size;
                end
            end
        end

        % Value changed function: ColoursDropDown
        function ColoursDropDownValueChanged(app, event)
            value = app.ColoursDropDown.Value;
            num = app.Num;

            if value == "Multiple Data Sets"
                app.ColorPallet = distinguishable_colors(app,num);
            else
                app.ColorPallet = turbo(num);
            end
            app.currentObj.UserData = []; % Reset colouring bool
            app.indiColors(app.ObjIndex,1) = false; % Reset external bool

            if strcmp(app.currentObj.Type,"scatter")
                for i = 1:num
                    app.objects(i).MarkerEdgeColor = app.ColorPallet(i,:);
                    app.objects(i).MarkerFaceColor = app.objects(i).MarkerEdgeColor;
                end
            else
                for i = 1:num
                    app.objects(i).Color = app.ColorPallet(i,:);
                end
            end
            setFontColor(app);
        end

        % Value changed function: InvertY
        function InvertYValueChanged(app, event)
            value = string(app.InvertY.Value);
            tf = strcmp(value,'On');
            if tf == 1
                set(app.ax, 'YDir', 'reverse');
            else
                set(app.ax, 'YDir', 'normal');
            end
        end

        % Value changed function: InvertX
        function InvertXValueChanged(app, event)
            value = string(app.InvertX.Value);
            tf = strcmp(value,'On');
            if tf == 1
                set(app.ax, 'XDir', 'reverse');
            else
                set(app.ax, 'XDir', 'normal');
            end
        end

        % Value changed function: LowerXEditField
        function LowerXEditFieldValueChanged(app, event)
            app.ax.XLim = [app.LowerXEditField.Value app.UpperXEditField.Value];
            app.ax.YLim = [app.LowerYEditField.Value app.UpperYEditField.Value];
        end

        % Value changed function: UpperXEditField
        function UpperXEditFieldValueChanged(app, event)
            app.ax.XLim = [app.LowerXEditField.Value app.UpperXEditField.Value];
            app.ax.YLim = [app.LowerYEditField.Value app.UpperYEditField.Value];
        end

        % Value changed function: LowerYEditField
        function LowerYEditFieldValueChanged(app, event)
            app.ax.XLim = [app.LowerXEditField.Value app.UpperXEditField.Value];
            app.ax.YLim = [app.LowerYEditField.Value app.UpperYEditField.Value];
        end

        % Value changed function: UpperYEditField
        function UpperYEditFieldValueChanged(app, event)
            app.ax.XLim = [app.LowerXEditField.Value app.UpperXEditField.Value];
            app.ax.YLim = [app.LowerYEditField.Value app.UpperYEditField.Value];
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Reset limits back the inital limits on first data read
            app.ax.XLim = app.initialX;
            app.ax.YLim = app.initialY;

            % Apply these limits to reflect GUI values
            app.LowerXEditField.Value = app.ax.XLim(1);
            app.UpperXEditField.Value = app.ax.XLim(2);
            app.LowerYEditField.Value = app.ax.YLim(1);
            app.UpperYEditField.Value = app.ax.YLim(2);
        end

        % Button pushed function: SpanButton
        function SpanButtonPushed(app, event)
            % Set limits back to auto to find data and add some padding
            xlim('auto');
            xlim('padded');

            ylim('auto');
            ylim('padded');

            % Apply these limits to reflect GUI values
            app.LowerXEditField.Value = app.ax.XLim(1);
            app.UpperXEditField.Value = app.ax.XLim(2);
            app.LowerYEditField.Value = app.ax.YLim(1);
            app.UpperYEditField.Value = app.ax.YLim(2);
        end

        % Value changed function: FigPresetDropDown
        function FigPresetDropDownValueChanged(app, event)
            value = app.FigPresetDropDown.Value;

            % Keep fig stationary, only change aspect ratio
            CurrentFigPos = app.fig.Position;

            if value == "Default"
                % Calculate difference w predetermined height to keep top left anchored
                currentTopLeft = app.fig.InnerPosition(2) + app.fig.InnerPosition(4);
                newTopLeft = currentTopLeft - 8;

                app.ax.OuterPosition = [0 0 14.8167 8];
                app.fig.InnerPosition = [CurrentFigPos(1) newTopLeft 14.8167 8];

            elseif value == "Horizontal, 1 column wide"
                % Calculate difference w predetermined height to keep top left anchored
                currentTopLeft = app.fig.InnerPosition(2) + app.fig.InnerPosition(4);
                newTopLeft = currentTopLeft - 6.15;

                app.ax.OuterPosition = [0 0 9.70 6.15];
                app.fig.InnerPosition = [CurrentFigPos(1) newTopLeft 9.70 6.15];

            elseif value == "Horizontal, 2 columns wide"
                % Calculate difference w predetermined height to keep top left anchored
                currentTopLeft = app.fig.InnerPosition(2) + app.fig.InnerPosition(4);
                newTopLeft = currentTopLeft - 8.88;

                app.ax.OuterPosition = [0 0 17.65 8.88];
                app.fig.InnerPosition = [CurrentFigPos(1) newTopLeft 17.65 8.88];

            elseif value == "Vertical"
                % Calculate difference w predetermined height to keep top left anchored
                currentTopLeft = app.fig.InnerPosition(2) + app.fig.InnerPosition(4);
                newTopLeft = currentTopLeft - 8.88;

                app.ax.OuterPosition = [0 0 5.6 8.88];
                app.fig.InnerPosition = [CurrentFigPos(1) newTopLeft 5.6 8.88];

            elseif value == "Square"
                % Calculate difference w predetermined height to keep top left anchored
                currentTopLeft = app.fig.InnerPosition(2) + app.fig.InnerPosition(4);
                newTopLeft = currentTopLeft - 8.88;

                app.ax.OuterPosition = [0 0 9.9 8.88];
                app.fig.InnerPosition = [CurrentFigPos(1) newTopLeft 9.9 8.88];
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %% Callback function to handle loading of preset
            % Select the preset file
            [file, folder] = uigetfile('*.xml', "Select a Settings File");
            full = fullfile(folder, file);

            % Load file into struct
            state = readstruct(full);
            %state = state.state;

            % Load headers
            for i = 1:length(app.headersToSave)
                app.(app.headersToSave{i}).Text = state.(app.headersToSave{i}).Text;
            end

            % Load labels
            for i = 1:length(app.labelsToSave)
                app.(app.labelsToSave{i}).Text = state.(app.labelsToSave{i}).Text;
            end

            % Load values
            for i = 1:length(app.valuesToSave)
                % Saving/loading in XML changes 0/1 logicals to true/false.
                % Need to convert back.
                if strcmp(state.(app.valuesToSave{i}).Value,'true') == 1
                    state.(app.valuesToSave{i}).Value = true;
                elseif strcmp(state.(app.valuesToSave{i}).Value,'false') == 1
                    state.(app.valuesToSave{i}).Value = false;
                end

                app.(app.valuesToSave{i}).Value = state.(app.valuesToSave{i}).Value;
            end

            % Now assign/update all preset values back to plot
            % Achieve this simply by calling every callback to utilize existing code
            repopulate(app);
            LegendEditFieldValueChanged(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            state = struct();

            % Save headers
            for i = 1:length(app.headersToSave)
                state.(app.headersToSave{i}).Text = app.(app.headersToSave{i}).Text;
            end

            % Save labels
            for i = 1:length(app.labelsToSave)
                state.(app.labelsToSave{i}).Text = app.(app.labelsToSave{i}).Text;
            end

            % Save values
            for i = 1:length(app.valuesToSave)
                state.(app.valuesToSave{i}).Value = app.(app.valuesToSave{i}).Value;
            end

            % Open prompt to save variable to XML file
            [saveFile,savePath] = uiputfile('*.xml','Save Settings');

            saveFull = fullfile(savePath,saveFile);
            writestruct(state,saveFull);
        end

        % Button pushed function: ScriptButton
        function ScriptButtonPushed(app, event)
            % Count how many times the button is pressed to add to name
            app.ScriptButton.UserData = app.ScriptButton.UserData + 1;

            % Create cell array of variable values to save
            script = cell(size(app.valuesToSave,2),1);

            % Save values into variables
            for i = 1:length(app.valuesToSave)
                script{i,:} = app.(app.valuesToSave{i}).Value;
            end

            newColours = zeros(size(app.indiColors,1),3);

            % Figure out individual colours
            if any(app.indiColors)
                % Incase there is a legend present, changes dot notation/index
                check = isgraphics(app.currentHandle.Children);
                if size(check,1) > 1 % If the Children is not just the axes
                    flippedAxes = flip(app.currentHandle.Children);
                    axesChild = flippedAxes(1); % IS THIS ALWAYS 1? AXES COMES FIRST THEN LEGEND AND OTHERS?
                else
                    axesChild = app.currentHandle.Children;
                end

                Objs = axesChild.Children;
                Objs = flip(Objs);

                for i = 1:size(Objs,1)
                    if app.indiColors(i,1) == true
                        if strcmp(Objs(i,1).Type,'scatter')
                            newColours(app.indiColors(i,1),:) = Objs(i,1).MarkerEdgeColor;
                        else
                            newColours(app.indiColors(i,1),:) = Objs(i,1).Color; % Record colors of changed objects
                        end
                    end
                end
            end

            % Create singular variable names to use throughout exported script
            y_scale = script(1,1);
            x_scale = script(2,1);
            y_label = script(3,1);
            x_label = script(4,1);
            checkbox = script(5,1);
            legends = script(6,1);
            if isempty(legends)
                legends = cell(app.Num);
            end
            ynot = script(7,1);
            xnot = script(8,1);
            style = app.LineStyle;
            width = script(10,1);
            marker = app.Marker;
            markerSize = script(12,1);
            colourType = script(13,1);
            invertY = script(14,1);
            invertX = script(15,1);
            lowerX = script(16,1);
            upperX = script(17,1);
            lowerY = script(18,1);
            upperY = script(19,1);
            tickspace = script(20,1);
            preset = script(21,1);
            individuals = newColours;
            indices = app.indiColors;

            % List of all the variable names we want to save
            names = {"y_scale", "x_scale", "y_label", "x_label", "checkbox", "legends", "ynot", "xnot", "style", "width", "marker", "markerSize", "colourType", "invertY", "invertX","lowerX", "upperX", "lowerY", "upperY", "tickspace", "preset","individuals","indices"}; %#ok<CLARRSTR>

            % Create script name depending on iteration of button pressed
            file = sprintf("OnePanelScript_%i.m",app.ScriptButton.UserData);

            % Create new script and save variables to the top
            matlab.io.saveVariablesToScript(file, names, "SaveMode","create");

            % Append the rest of the default script to use the above variables in one file
            % Read data into string array and open writing script
            text = readlines("external scripts\general.m");
            [fid,msg] = fopen(file,'at');

            % Print into file
            assert(fid>=3,msg)
            for i = 1:size(text,1)
                fprintf(fid,'%s\n',text(i,1));
            end

            % Close the file
            fclose(fid);

            % Open the exported script to be run
            edit OnePanelScript_1.m %There is no way to add var i in this name so always open first and can select others from cd
        end

        % Close request function: OnePanelFigUIFigure
        function OnePanelFigUIFigureCloseRequest(app, event)
            % Create a pre-close check to make sure user isn't losing valuable preset
            uiconfirm(app.OnePanelFigUIFigure, "Do you want to save your current configuration to a preset?","Save Preset?", "Options", {'Yes', 'No'}, "CloseFcn", @(src,event)dlgcallback(app,src,event));
        end

        % Button pushed function: FaceColourButton
        function FaceColourButtonPushed(app, event)
            %Pick color manually
            color = uisetcolor();
            if color == 0
                return
            end

            app.currentObj.MarkerFaceColor = color;
            app.currentObj.UserData = 1; % UserData for this GUI
            app.indiColors(app.ObjIndex,1) = true; % indiColors for external scripts
        end

        % Button pushed function: EdgeColourButton
        function EdgeColourButtonPushed(app, event)
            %Pick color manually
            color = uisetcolor();
            if color == 0
                return
            end

            app.currentObj.MarkerEdgeColor = color;
            app.currentObj.UserData = 1; % UserData for this GUI
            app.indiColors(app.ObjIndex,1) = true; % indiColors for external scripts
        end

        % Value changed function: XTickSpacingEditField
        function XTickSpacingEditFieldValueChanged(app, event)
            value = app.XTickSpacingEditField.Value;
            lowx = app.LowerXEditField.Value;
            upx = app.UpperXEditField.Value;

            xticks(app.ax,lowx:value:upx);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.OnePanelFigUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {264, '1x', 257};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.OnePanelFigUIFigure = uifigure('Visible', 'off');
            app.OnePanelFigUIFigure.Name = 'OnePanelFig';
            app.OnePanelFigUIFigure.Icon = fullfile(pathToMLAPP, 'OnePanelFig_resources', 'logo.png');
            app.OnePanelFigUIFigure.CloseRequestFcn = createCallbackFcn(app, @OnePanelFigUIFigureCloseRequest, true);
            app.OnePanelFigUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.OnePanelFigUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RulerHeader = uilabel(app.LeftPanel);
            app.RulerHeader.Text = 'RULER NOTATION';
            app.YScaleDropDownLabel = uilabel(app.LeftPanel);
            app.YScaleDropDownLabel.Text = 'Y-Scale';
            app.XAxisEditFieldLabel = uilabel(app.LeftPanel);
            app.XAxisEditFieldLabel.Text = 'X-Axis';
            app.YAxisEditFieldLabel = uilabel(app.LeftPanel);
            app.YAxisEditFieldLabel.Text = 'Y-Axis';
            app.LegendEditFieldLabel = uilabel(app.LeftPanel);
            app.LegendEditFieldLabel.Text = 'Legend';
            app.LegendHeader = uilabel(app.LeftPanel);
            app.LegendHeader.Text = 'LEGEND LABELS ';
            app.AxesHeader = uilabel(app.LeftPanel);
            app.AxesHeader.Text = 'AXES LABELS';
            app.ScaleHeader = uilabel(app.LeftPanel);
            app.ScaleHeader.Text = 'SCALE TYPE';
            app.DelimiterLabel = uilabel(app.LeftPanel);
            app.DelimiterLabel.Text = '(Use , as a delimiter)';
            app.XScaleDropDownLabel = uilabel(app.LeftPanel);
            app.XScaleDropDownLabel.Text = 'X-Scale';
            app.YScaleDropDown = uidropdown(app.LeftPanel);
            app.YScaleDropDown.Items = {'Linear', 'Log'};
            app.YScaleDropDown.ValueChangedFcn = createCallbackFcn(app, @YScaleDropDownValueChanged, true);
            app.YScaleDropDown.Value = 'Linear';
            app.XScaleDropDown = uidropdown(app.LeftPanel);
            app.XScaleDropDown.Items = {'Linear', 'Log'};
            app.XScaleDropDown.ValueChangedFcn = createCallbackFcn(app, @XScaleDropDownValueChanged, true);
            app.XScaleDropDown.Value = 'Linear';
            app.YAxisEditField = uieditfield(app.LeftPanel, 'text');
            app.YAxisEditField.ValueChangedFcn = createCallbackFcn(app, @YAxisEditFieldValueChanged, true);
            app.XAxisEditField = uieditfield(app.LeftPanel, 'text');
            app.XAxisEditField.ValueChangedFcn = createCallbackFcn(app, @XAxisEditFieldValueChanged, true);
            app.BoldAxesCheckBox = uicheckbox(app.LeftPanel);
            app.BoldAxesCheckBox.ValueChangedFcn = createCallbackFcn(app, @BoldAxesCheckBoxValueChanged, true);
            app.BoldAxesCheckBox.Text = 'Bold Axes';
            app.LegendEditField = uieditfield(app.LeftPanel, 'text');
            app.LegendEditField.ValueChangedFcn = createCallbackFcn(app, @LegendEditFieldValueChanged, true);
            app.YAxisSciNotationCheckBox = uicheckbox(app.LeftPanel);
            app.YAxisSciNotationCheckBox.ValueChangedFcn = createCallbackFcn(app, @YAxisSciNotationCheckBoxValueChanged, true);
            app.YAxisSciNotationCheckBox.Text = 'Y-Axis Sci-Notation';
            app.XAxisSciNotationCheckBox = uicheckbox(app.LeftPanel);
            app.XAxisSciNotationCheckBox.ValueChangedFcn = createCallbackFcn(app, @XAxisSciNotationCheckBoxValueChanged, true);
            app.XAxisSciNotationCheckBox.Text = 'X-Axis Sci-Notation';
            app.CenterPanel = uipanel(app.GridLayout);
            app.ColoursDropDownLabel = uilabel(app.CenterPanel);
            app.ColoursDropDownLabel.Text = 'Colours';
            app.ColoursHeader = uilabel(app.CenterPanel);
            app.ColoursHeader.Text = 'COLOURS';
            app.MarkerSizeEditFieldLabel = uilabel(app.CenterPanel);
            app.MarkerSizeEditFieldLabel.Text = 'MarkerSize';
            app.StyleHeader = uilabel(app.CenterPanel);
            app.StyleHeader.Text = 'STYLE';
            app.LineStyleDropDownLabel = uilabel(app.CenterPanel);
            app.LineStyleDropDownLabel.Text = 'LineStyle';
            app.FiguresHeader = uilabel(app.CenterPanel);
            app.FiguresHeader.Text = 'POPULATED FIGURES';
            app.MarkerTypeDropDownLabel = uilabel(app.CenterPanel);
            app.MarkerTypeDropDownLabel.Text = 'MarkerType';
            app.LineWidthEditFieldLabel = uilabel(app.CenterPanel);
            app.LineWidthEditFieldLabel.Text = 'LineWidth';
            app.FiguresDropDownLabel = uilabel(app.CenterPanel);
            app.FiguresDropDownLabel.Text = 'Figures';
            app.FiguresDropDown = uidropdown(app.CenterPanel);
            app.FiguresDropDown.Items = {};
            app.FiguresDropDown.ValueChangedFcn = createCallbackFcn(app, @FiguresDropDownValueChanged, true);
            app.FiguresDropDown.Value = {};
            app.AxesObjectsDropDown = uidropdown(app.CenterPanel);
            app.AxesObjectsDropDown.Items = {};
            app.AxesObjectsDropDown.ValueChangedFcn = createCallbackFcn(app, @AxesObjectsDropDownValueChanged, true);
            app.AxesObjectsDropDown.Value = {};
            app.UpdateButton = uibutton(app.CenterPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.LineStyleDropDown = uidropdown(app.CenterPanel);
            app.LineStyleDropDown.Items = {'Solid', 'Dashed', 'Dotted', 'Dash-Dot'};
            app.LineStyleDropDown.ValueChangedFcn = createCallbackFcn(app, @LineStyleDropDownValueChanged, true);
            app.LineStyleDropDown.Value = 'Solid';
            app.LineWidthEditField = uieditfield(app.CenterPanel, 'numeric');
            app.LineWidthEditField.ValueChangedFcn = createCallbackFcn(app, @LineWidthEditFieldValueChanged, true);
            app.LineWidthEditField.Value = 2;
            app.MarkerTypeDropDown = uidropdown(app.CenterPanel);
            app.MarkerTypeDropDown.Items = {'None', 'Square', 'Circle', 'Point', 'Plus Sign', 'Asterisk', 'Cross', 'Horizontal Line', 'Vertical Line', 'Diamond'};
            app.MarkerTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MarkerTypeDropDownValueChanged, true);
            app.MarkerTypeDropDown.Value = 'None';
            app.MarkerSizeEditField = uieditfield(app.CenterPanel, 'numeric');
            app.MarkerSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MarkerSizeEditFieldValueChanged, true);
            app.MarkerSizeEditField.Value = 10;
            app.ColoursDropDown = uidropdown(app.CenterPanel);
            app.ColoursDropDown.Items = {'Multiple Data Sets', 'Trends'};
            app.ColoursDropDown.ValueChangedFcn = createCallbackFcn(app, @ColoursDropDownValueChanged, true);
            app.ColoursDropDown.Value = 'Multiple Data Sets';
            app.AxesObjectsDropDownLabel = uilabel(app.CenterPanel);
            app.AxesObjectsDropDownLabel.Text = 'Axes Objects';
            app.Button = uibutton(app.CenterPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @PaletteButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'OnePanelFig_resources', 'icon-mat-palette.png');
            app.Button.Text = '';
            app.FaceColourButton = uibutton(app.CenterPanel, 'push');
            app.FaceColourButton.ButtonPushedFcn = createCallbackFcn(app, @FaceColourButtonPushed, true);
            app.FaceColourButton.Enable = 'off';
            app.FaceColourButton.Text = 'Face Colour';
            app.EdgeColourButton = uibutton(app.CenterPanel, 'push');
            app.EdgeColourButton.ButtonPushedFcn = createCallbackFcn(app, @EdgeColourButtonPushed, true);
            app.EdgeColourButton.Enable = 'off';
            app.EdgeColourButton.Text = 'Edge Colour';
            app.RightPanel = uipanel(app.GridLayout);
            app.PresetsHeader = uilabel(app.RightPanel);
            app.PresetsHeader.Text = 'PRESETS';
            app.LimitsHeader = uilabel(app.RightPanel);
            app.LimitsHeader.Text = 'LIMITS';
            app.InvertYLabel = uilabel(app.RightPanel);
            app.InvertYLabel.Text = 'Y-Axis';
            app.InvertXLabel = uilabel(app.RightPanel);
            app.InvertXLabel.Text = 'X-Axis';
            app.InvertHeader = uilabel(app.RightPanel);
            app.InvertHeader.Text = 'INVERT AXES';
            app.FigPresetDropDownLabel = uilabel(app.RightPanel);
            app.FigPresetDropDownLabel.Text = 'Fig. Preset';
            app.LowerXEditFieldLabel = uilabel(app.RightPanel);
            app.LowerXEditFieldLabel.Text = 'Lower X';
            app.LowerYEditFieldLabel = uilabel(app.RightPanel);
            app.LowerYEditFieldLabel.Text = 'Lower Y';
            app.UpperYEditFieldLabel = uilabel(app.RightPanel);
            app.UpperYEditFieldLabel.Text = 'Upper Y';
            app.UpperXEditFieldLabel = uilabel(app.RightPanel);
            app.UpperXEditFieldLabel.Text = 'Upper X';
            app.InvertY = uiswitch(app.RightPanel, 'toggle');
            app.InvertY.ValueChangedFcn = createCallbackFcn(app, @InvertYValueChanged, true);
            app.InvertX = uiswitch(app.RightPanel, 'toggle');
            app.InvertX.ValueChangedFcn = createCallbackFcn(app, @InvertXValueChanged, true);
            app.LowerXEditField = uieditfield(app.RightPanel, 'numeric');
            app.LowerXEditField.ValueChangedFcn = createCallbackFcn(app, @LowerXEditFieldValueChanged, true);
            app.UpperXEditField = uieditfield(app.RightPanel, 'numeric');
            app.UpperXEditField.ValueChangedFcn = createCallbackFcn(app, @UpperXEditFieldValueChanged, true);
            app.LowerYEditField = uieditfield(app.RightPanel, 'numeric');
            app.LowerYEditField.ValueChangedFcn = createCallbackFcn(app, @LowerYEditFieldValueChanged, true);
            app.UpperYEditField = uieditfield(app.RightPanel, 'numeric');
            app.UpperYEditField.ValueChangedFcn = createCallbackFcn(app, @UpperYEditFieldValueChanged, true);
            app.ResetButton = uibutton(app.RightPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SpanButton = uibutton(app.RightPanel, 'push');
            app.SpanButton.ButtonPushedFcn = createCallbackFcn(app, @SpanButtonPushed, true);
            app.SpanButton.Text = 'Span';
            app.XTickSpacingEditField = uieditfield(app.RightPanel, 'numeric');
            app.XTickSpacingEditField.ValueChangedFcn = createCallbackFcn(app, @XTickSpacingEditFieldValueChanged, true);
            app.XTickSpacingEditField.Value = 1;
            app.FigPresetDropDown = uidropdown(app.RightPanel);
            app.FigPresetDropDown.Items = {'Default', 'Horizontal, 1 column wide', 'Horizontal, 2 columns wide', 'Vertical', 'Square'};
            app.FigPresetDropDown.ValueChangedFcn = createCallbackFcn(app, @FigPresetDropDownValueChanged, true);
            app.FigPresetDropDown.Value = 'Default';
            app.LoadButton = uibutton(app.RightPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.RightPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ScriptButton = uibutton(app.RightPanel, 'push');
            app.ScriptButton.ButtonPushedFcn = createCallbackFcn(app, @ScriptButtonPushed, true);
            app.ScriptButton.Text = 'Script';
            app.XTickSpacingEditFieldLabel = uilabel(app.RightPanel);
            app.XTickSpacingEditFieldLabel.Text = 'XTick Spacing';
            % Show the figure after all components are created
            app.OnePanelFigUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OnePanelFig

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.OnePanelFigUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.OnePanelFigUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OnePanelFigUIFigure)
        end
    end
end